classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        equationSolution               matlab.ui.container.Tab
        ChonPhuongPhap                 matlab.ui.control.DropDown
        ChnphngphpDropDownLabel        matlab.ui.control.Label
        equationField                  matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        summit                         matlab.ui.control.Button
        SoLanLap                       matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        KetQuaNghiem                   matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel        matlab.ui.control.Label
        errorField                     matlab.ui.control.NumericEditField
        SaisEditFieldLabel             matlab.ui.control.Label
        endField                       matlab.ui.control.NumericEditField
        startField                     matlab.ui.control.NumericEditField
        KhongphnlyEditFieldLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        interpolationResult            matlab.ui.control.EditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        ErrorsHandleResult             matlab.ui.control.EditField
        interpolationNumber            matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        interpolationPolynomial        matlab.ui.control.EditField
        athcnisuyEditFieldLabel        matlab.ui.control.Label
        Compute                        matlab.ui.control.Button
        ErrorsHandle                   matlab.ui.control.Label
        technique                      matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel   matlab.ui.control.Label
        yArray                         matlab.ui.control.EditField
        NhpccgitrycchnhaubikhongtrngEditFieldLabel  matlab.ui.control.Label
        xArray                         matlab.ui.control.EditField
        NhpccgitrxcchnhaubikhongtrngEditFieldLabel  matlab.ui.control.Label
        UIAxes_Interpolation           matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        hoiQuyErrorMessage             matlab.ui.control.Label
        phuongTrinhHoiQuyLabel         matlab.ui.control.Label
        phuongTrinhHoiQuyTitle         matlab.ui.control.Label
        hoiQuyDrawerButton             matlab.ui.control.Button
        hoiQuyPredictionResultLabel    matlab.ui.control.Label
        hoiQuyPredictionResultTitle    matlab.ui.control.Label
        hoiQuyPredictionButton         matlab.ui.control.Button
        hoiQuyPredictorEditField       matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        hoiQuyDuLieuXField             matlab.ui.control.EditField
        NhpdliuyEditField_3Label_2     matlab.ui.control.Label
        hoiQuyDuLieuYField             matlab.ui.control.EditField
        NhpdliuxEditField_3Label_2     matlab.ui.control.Label
        hoiQuyMethodButtonGroup        matlab.ui.container.ButtonGroup
        exponentialOptionButton        matlab.ui.control.RadioButton
        regressionOptionButton         matlab.ui.control.RadioButton
        hoiquyUIAxes                   matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        derivativeResult               matlab.ui.control.NumericEditField
        KtquEditFieldLabel_2           matlab.ui.control.Label
        derivativeValue                matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        methodCalc                     matlab.ui.control.ListBox
        derivativeErrHandle            matlab.ui.control.EditField
        ButtonGroup                    matlab.ui.container.ButtonGroup
        derivativeErrorsHandle         matlab.ui.control.EditField
        derivativeComputef             matlab.ui.control.Button
        Computexy                      matlab.ui.control.Button
        errorOh                        matlab.ui.control.DropDown
        ChnbcsaisDropDownLabel         matlab.ui.control.Label
        hInput                         matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        expressionInput                matlab.ui.control.EditField
        NhphmsEditFieldLabel_2         matlab.ui.control.Label
        yArray_2                       matlab.ui.control.EditField
        NhpccgitrycchnhaubikhongtrngLabel  matlab.ui.control.Label
        xArray_                        matlab.ui.control.EditField
        NhpccgitrxcchnhaubikhongtrngLabel  matlab.ui.control.Label
        HocLabel_2                     matlab.ui.control.Label
        TchphnTab                      matlab.ui.container.Tab
        resultTichPhan                 matlab.ui.control.Label
        tichphanResultLabel            matlab.ui.control.Label
        tichphanErrorLabel             matlab.ui.control.Label
        canTichPhanBField              matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        canTichPhanAField              matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        NhpcntnhTchphnLabel            matlab.ui.control.Label
        solveTichPhanButton            matlab.ui.control.Button
        nField                         matlab.ui.control.NumericEditField
        NhpNEditFieldLabel             matlab.ui.control.Label
        tichPhanOptionsButtonGroup     matlab.ui.container.ButtonGroup
        Simpson38Button                matlab.ui.control.RadioButton
        Simpson13Button                matlab.ui.control.RadioButton
        HnhthangButton                 matlab.ui.control.RadioButton
        hamTichPhanField               matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        HocLabel                       matlab.ui.control.Label
        dulieuYField                   matlab.ui.control.EditField
        NhpdliuyEditField_3Label       matlab.ui.control.Label
        dulieuXField                   matlab.ui.control.EditField
        NhpdliuxEditField_3Label       matlab.ui.control.Label
        GiithiunhmTab                  matlab.ui.container.Tab
        ThnhvinLabel                   matlab.ui.control.Label
        CodevthitkgiaodinLabel         matlab.ui.control.Label
        VitbocohiuchnhLabel            matlab.ui.control.Label
        CodevthitkgiaodinLabel_2       matlab.ui.control.Label
        CodevthitkgiaodinLabel_3       matlab.ui.control.Label
        NhimvLabel                     matlab.ui.control.Label
        TrnhnhThnh21200354Label        matlab.ui.control.Label
        DngVnThng21200359Label         matlab.ui.control.Label
        NguynTunKit21200303Label       matlab.ui.control.Label
        NguynVLcLam21200305Label       matlab.ui.control.Label
        NhmTHTHLabel                   matlab.ui.control.Label
        ncuimnPhngphptnhLabel          matlab.ui.control.Label
        TrngihcKhoahcTnhinHQGTPHChmnhLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function equationFieldValueChanging(app, event)
            
        end

        % Button pushed function: summit
        function summitButtonPushed(app, event)
            Option = app.ChonPhuongPhap.Value;
            f = app.equationField.Value;
            a = app.startField.Value;
            b = app.endField.Value;
            saiso = app.errorField.Value;
            if (Option == "Chia đôi")
                [nghiem, solanlap] = chiadoi(f, a, b, saiso);
                app.KetQuaNghiem.Value = nghiem;
                app.SoLanLap.Value = solanlap; 
            elseif (Option == "Lặp")
                fb = f;
                [nghiem, solanlap] = lap(fb,a,b,saiso);
                app.KetQuaNghiem.Value = nghiem;
                app.SoLanLap.Value = solanlap; 
            elseif (Option == "Tiếp tuyến")
                [nghiem, solanlap] = tieptuyen(f,a,b,saiso);
                app.KetQuaNghiem.Value = nghiem;
                app.SoLanLap.Value = solanlap; 
            end    
            syms x;
            f = str2sym(f);
            f = symfun(f,x);
            fplot(app.UIAxes,f);
        end

        % Callback function
        function startFieldValueChanged(app, event)
            
        end

        % Button pushed function: solveTichPhanButton
        function solveTichPhanButtonPushed(app, event)
            try
                x = app.dulieuXField.Value;
                y = app.dulieuYField.Value;

                if (app.hamTichPhanField.Value) 
                    fx = app.hamTichPhanField.Value;
                    fx = str2func(['@(x)' fx]);
                else
                    x = str2num(x);
                    y = str2num(y);
                    fx = Lagrange(x, y);
                end
                a = app.canTichPhanAField.Value;
                b = app.canTichPhanBField.Value;

                % Error handler
                if (a > b)
                    error("Cận a phải nhỏ hơn cận b");
                end
                n = app.nField.Value;
                if (n == 0)
                    error("n phải khác 0")
                end
    
                % Compute
                option = app.tichPhanOptionsButtonGroup.SelectedObject.Value;
    
                kq = 0;
                if (option == app.HnhthangButton.Value) 
                    kq = tichphanhinhthang(fx, a, b, n);  
                elseif (option == app.Simpson13Button.Value)
                    kq = intervalBySimpson13(fx, a, b, n);
                elseif (option == app.Simpson38Button.Value)
                    kq = intervalBySimpson38(fx, a, b, n);
                end
                  
                app.resultTichPhan.Text = num2str(kq);
                app.tichphanErrorLabel.Text = '';
                app.resultTichPhan.Visible = true;
                app.tichphanResultLabel.Visible = true;
            catch exception
                app.tichphanErrorLabel.Text = exception.message;
                app.resultTichPhan.Visible = false;
                app.tichphanResultLabel.Visible = false;
            end
            
        end

        % Button pushed function: hoiQuyDrawerButton
        function hoiQuyDrawerButtonPushed(app, event)
            try
                a = app.hoiQuyDuLieuXField.Value;
                a = str2num(a);
                b = app.hoiQuyDuLieuYField.Value;
                b = str2num(b);
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.regressionOptionButton.Value)
                    fx = linearRegression(a, b);
                else 
                    fx = exponentialRegression(a, b);
                end

%                 n = min(a) : 0.1 : max(a);
%                 plot(app.hoiquyUIAxes, n, fx(n), 'b', 'LineWidth', 1);
                fplot(app.hoiquyUIAxes,fx);
                grid(app.hoiquyUIAxes, 'on');
                axis(app.hoiquyUIAxes);
              
    
                s = func2str(fx);
                app.phuongTrinhHoiQuyLabel.Text = s(5:end);
                app.hoiQuyErrorMessage.Text = '';
                app.phuongTrinhHoiQuyLabel.Visible = true;
                app.phuongTrinhHoiQuyTitle.Visible = true;
                app.hoiQuyPredictionResultLabel.Visible = true;
                app.hoiQuyPredictionResultTitle.Visible = true;
            catch e
                app.hoiQuyErrorMessage.Text = e.message;

                app.phuongTrinhHoiQuyLabel.Visible = false;
                app.phuongTrinhHoiQuyTitle.Visible = false;
                app.hoiQuyPredictionResultLabel.Visible = false;
                app.hoiQuyPredictionResultTitle.Visible = false;
            end
        end

        % Button pushed function: hoiQuyPredictionButton
        function hoiQuyPredictionButtonPushed(app, event)
            try
                a = app.hoiQuyDuLieuXField.Value;
                a = str2num(a);
                b = app.hoiQuyDuLieuYField.Value;
                b = str2num(b);
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.regressionOptionButton.Value)
                    fx = linearRegression(a, b);
                elseif (option == app.exponentialOptionButton.Value)
                    fx = exponentialRegression(a, b);
                elseif (option == app.logaritOptionButton.Value) 
                    fx = logarithmicRegression(a, b);
                end
                
                x = app.hoiQuyPredictorEditField.Value;
                app.hoiQuyPredictionResultLabel.Text = num2str(fx(x));
                app.hoiQuyErrorMessage.Text = '';
            catch e
                app.hoiQuyErrorMessage.Text = e.message;
                disp(e);
            end
        end

        % Button pushed function: Computexy
        function ComputexyButtonPushed(app, event)
            app.derivativeErrHandle.Visible = 'off';
            app.derivativeErrorsHandle.Visible = 'off';
            idx = 0;
            value = app.derivativeValue.Value;
            result = app.derivativeResult.Value;
            x = str2num(app.xArray.Value);
            y = str2num(app.yArray_2.Value); 
            for i = 1:length(x)
                if x(i) == value
                    idx = i;
                end
            end
            if length(x) == length(y)
                if idx == 0
                    app.derivativeErrHandle.Visible = 'on';
                    app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!";
                elseif idx ~= 0
                    if (app.methodCalc.Value == "xấp xỉ tiến")
                        if idx == length(x)
                            app.derivativeErrHandle.Visible = 'on';
                            app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx + 1) - y(idx))/(x(idx + 1) - x(idx));
                        end
                    elseif (app.methodCalc.Value == "xấp xỉ lùi")
                        if idx == 1
                            app.derivativeErrHandle.Visible = 'on';
                            app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx) - y(idx - 1))/(x(idx) - x(idx - 1));
                        end
                    elseif (app.methodCalc.Value == "trung tâm")
                        if idx == 1 || idx == length(x)
                            app.derivativeErrHandle.Visible = 'on';
                            app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx + 1) - y(idx - 1))/(x(idx + 1) - x(idx - 1));
                        end
                    end
                end
            elseif length(x) ~= length(y)
                app.derivativeErrorsHandle.Visible = 'on';
                app.derivativeErrorsHandle.Value = "Oops, make sure the length of x and y is equal!!!";
            end
            app.derivativeResult.Value = result;
        end

        % Button pushed function: derivativeComputef
        function derivativeComputefButtonPushed(app, event)
            app.derivativeErrHandle_2.Visible = 'off';
            value = app.derivativeValue.Value;
            result = app.derivativeResult.Value; 
            f = app.expressionInput.Value;
            f = str2func(['@(x)' f]);
            h = app.hInput.Value;
            if h ~= 0
                if (app.methodCalc.Value == "xấp xỉ tiến")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value + h) - f(value))/h;
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (-3*f(value) + 4*f(value + h) - f(value + 2*h))/(2*h);
                    end
                elseif (app.methodCalc.Value == "xấp xỉ lùi")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value) - f(value - h))/h;
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (3*f(value) - 4*f(value - h) + f(value - 2*h))/(2*h);
                    end
                elseif (app.methodCalc.Value == "trung tâm")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value + h) + f(value) - f(value - h))/(2*h);
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (f(value + h) + f(value) - f(value - h))/(2*h);
                    end 
                end
            else
                app.derivativeErrHandle.Visible = 'on';
                app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!";
            end       
            app.derivativeResult.Value = result;

        end

        % Value changed function: interpolationNumber
        function interpolationNumberValueChanged(app, event)
            value = app.interpolationNumber.Value;
            f = app.interpolationPolynomial.Value;
            f = str2func(['@(x)' char(f)]);
            app.interpolationResult.Value = string(f(value));
        end

        % Button pushed function: Compute
        function ComputeButtonPushed(app, event)
            x = str2num(app.xArray.Value);
            y = str2num(app.yArray.Value);
            app.interpolationResult.Value = " ";
            app.interpolationPolynomial.Value = " ";
            if (length(x) == length(y))
                app.ErrorsHandle.Visible = 'off';
                if (app.technique.Value == "Lagrange")
                    f = Lagrange(x,y);
                else (app.technique.Value == "Newton")
                    f = NewtonInterpolation(x,y);
                end
                if (f == 0)
                    app.interpolationPolynomial.Value = "0";
                else
                app.interpolationPolynomial.Value = string(simplify(f));
                end
                fplot(app.UIAxes_Interpolation,f);
                grid(app.UIAxes_Interpolation, 'on');
                axis(app.UIAxes_Interpolation, [-100 100 -100 100]);
                value = app.interpolationNumber.Value;
                f = app.interpolationPolynomial.Value;
                f = str2func(['@(x)' char(f)]);
                app.interpolationResult.Value = string(f(value));
            else
                app.ErrorsHandle.Visible = 'on';
                app.ErrorsHandle.Value = "Oops, make sure the length of x and y is equal!!!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.equationSolution = uitab(app.TabGroup);
            app.equationSolution.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.equationSolution);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KhongphnlyEditFieldLabel = uilabel(app.equationSolution);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly';
            app.startField = uieditfield(app.equationSolution, 'numeric');
            app.endField = uieditfield(app.equationSolution, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.equationSolution);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.errorField = uieditfield(app.equationSolution, 'numeric');
            app.KtqunghimEditFieldLabel = uilabel(app.equationSolution);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KetQuaNghiem = uieditfield(app.equationSolution, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.equationSolution);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.equationSolution, 'numeric');
            app.summit = uibutton(app.equationSolution, 'push');
            app.summit.ButtonPushedFcn = createCallbackFcn(app, @summitButtonPushed, true);
            app.summit.Text = 'Tính toán';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.equationSolution);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.equationField = uieditfield(app.equationSolution, 'text');
            app.ChnphngphpDropDownLabel = uilabel(app.equationSolution);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonPhuongPhap = uidropdown(app.equationSolution);
            app.ChonPhuongPhap.Items = {'Chia đôi', 'Lặp', 'Tiếp tuyến', ''};
            app.ChonPhuongPhap.Value = 'Chia đôi';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_Interpolation = uiaxes(app.NisuyTab);
            title(app.UIAxes_Interpolation, 'Đồ thị')
            xlabel(app.UIAxes_Interpolation, 'X')
            ylabel(app.UIAxes_Interpolation, 'Y')
            zlabel(app.UIAxes_Interpolation, 'Z')
            app.NhpccgitrxcchnhaubikhongtrngEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpccgitrxcchnhaubikhongtrngEditFieldLabel.Text = 'Nhập các giá trị x (cách nhau bởi khoảng trắng) :';
            app.xArray = uieditfield(app.NisuyTab, 'text');
            app.NhpccgitrycchnhaubikhongtrngEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpccgitrycchnhaubikhongtrngEditFieldLabel.Text = 'Nhập các giá trị y (cách nhau bởi khoảng trắng) :';
            app.yArray = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy :';
            app.technique = uidropdown(app.NisuyTab);
            app.technique.Items = {'Newton', 'Lagrange'};
            app.technique.Value = 'Newton';
            app.ErrorsHandle = uilabel(app.NisuyTab);
            app.ErrorsHandle.Text = '';
            app.Compute = uibutton(app.NisuyTab, 'push');
            app.Compute.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.Compute.Text = 'Tính toán';
            app.athcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.athcnisuyEditFieldLabel.Text = 'Đa thức nội suy';
            app.interpolationPolynomial = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.interpolationNumber = uieditfield(app.NisuyTab, 'numeric');
            app.interpolationNumber.ValueChangedFcn = createCallbackFcn(app, @interpolationNumberValueChanged, true);
            app.ErrorsHandleResult = uieditfield(app.NisuyTab, 'text');
            app.ErrorsHandleResult.Visible = 'off';
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.interpolationResult = uieditfield(app.NisuyTab, 'text');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.hoiquyUIAxes = uiaxes(app.HiquyTab);
            title(app.hoiquyUIAxes, 'Đồ thị')
            xlabel(app.hoiquyUIAxes, 'X')
            ylabel(app.hoiquyUIAxes, 'Y')
            zlabel(app.hoiquyUIAxes, 'Z')
            app.hoiQuyMethodButtonGroup = uibuttongroup(app.HiquyTab);
            app.hoiQuyMethodButtonGroup.Title = 'Chọn phương pháp';
            app.regressionOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.regressionOptionButton.Text = 'Tuyến tính';
            app.regressionOptionButton.Value = true;
            app.exponentialOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.exponentialOptionButton.Text = 'Hàm mũ';
            app.NhpdliuxEditField_3Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_3Label_2.Text = 'Nhập dữ liệu x';
            app.hoiQuyDuLieuYField = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_3Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_3Label_2.Text = 'Nhập dữ liệu y';
            app.hoiQuyDuLieuXField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.hoiQuyPredictorEditField = uieditfield(app.HiquyTab, 'numeric');
            app.hoiQuyPredictionButton = uibutton(app.HiquyTab, 'push');
            app.hoiQuyPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyPredictionButtonPushed, true);
            app.hoiQuyPredictionButton.Text = 'Tính';
            app.hoiQuyPredictionResultTitle = uilabel(app.HiquyTab);
            app.hoiQuyPredictionResultTitle.Visible = 'off';
            app.hoiQuyPredictionResultTitle.Text = 'Kết quả dự đoán: ';
            app.hoiQuyPredictionResultLabel = uilabel(app.HiquyTab);
            app.hoiQuyPredictionResultLabel.Visible = 'off';
            app.hoiQuyPredictionResultLabel.Text = '';
            app.hoiQuyDrawerButton = uibutton(app.HiquyTab, 'push');
            app.hoiQuyDrawerButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyDrawerButtonPushed, true);
            app.hoiQuyDrawerButton.Text = 'Tìm phương trình';
            app.phuongTrinhHoiQuyTitle = uilabel(app.HiquyTab);
            app.phuongTrinhHoiQuyTitle.Visible = 'off';
            app.phuongTrinhHoiQuyTitle.Text = 'Kết quả phương trình hồi quy';
            app.phuongTrinhHoiQuyLabel = uilabel(app.HiquyTab);
            app.phuongTrinhHoiQuyLabel.Visible = 'off';
            app.phuongTrinhHoiQuyLabel.Text = '';
            app.hoiQuyErrorMessage = uilabel(app.HiquyTab);
            app.hoiQuyErrorMessage.Text = '';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.ButtonGroup = uibuttongroup(app.ohmTab);
            app.HocLabel_2 = uilabel(app.ButtonGroup);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpccgitrxcchnhaubikhongtrngLabel = uilabel(app.ButtonGroup);
            app.NhpccgitrxcchnhaubikhongtrngLabel.Text = 'Nhập các giá trị x (cách nhau bởi khoảng trắng) :';
            app.xArray_ = uieditfield(app.ButtonGroup, 'text');
            app.NhpccgitrycchnhaubikhongtrngLabel = uilabel(app.ButtonGroup);
            app.NhpccgitrycchnhaubikhongtrngLabel.Text = 'Nhập các giá trị y (cách nhau bởi khoảng trắng) :';
            app.yArray_2 = uieditfield(app.ButtonGroup, 'text');
            app.NhphmsEditFieldLabel_2 = uilabel(app.ButtonGroup);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.expressionInput = uieditfield(app.ButtonGroup, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ButtonGroup);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.hInput = uieditfield(app.ButtonGroup, 'numeric');
            app.ChnbcsaisDropDownLabel = uilabel(app.ButtonGroup);
            app.ChnbcsaisDropDownLabel.Text = 'Chọn bậc sai số';
            app.errorOh = uidropdown(app.ButtonGroup);
            app.errorOh.Items = {'O(h)', 'O(h^2)'};
            app.errorOh.Value = 'O(h)';
            app.Computexy = uibutton(app.ButtonGroup, 'push');
            app.Computexy.ButtonPushedFcn = createCallbackFcn(app, @ComputexyButtonPushed, true);
            app.Computexy.Text = 'Tính toán';
            app.derivativeComputef = uibutton(app.ButtonGroup, 'push');
            app.derivativeComputef.ButtonPushedFcn = createCallbackFcn(app, @derivativeComputefButtonPushed, true);
            app.derivativeComputef.Text = 'Tính toán';
            app.derivativeErrorsHandle = uieditfield(app.ButtonGroup, 'text');
            app.derivativeErrorsHandle.Editable = 'off';
            app.derivativeErrorsHandle.Visible = 'off';
            app.derivativeErrHandle = uieditfield(app.ohmTab, 'text');
            app.derivativeErrHandle.Visible = 'off';
            app.methodCalc = uilistbox(app.ohmTab);
            app.methodCalc.Items = {'xấp xỉ tiến', 'xấp xỉ lùi', 'trung tâm'};
            app.methodCalc.Value = 'xấp xỉ tiến';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.derivativeValue = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel_2 = uilabel(app.ohmTab);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.derivativeResult = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.NhpdliuxEditField_3Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.dulieuXField = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.dulieuYField = uieditfield(app.TchphnTab, 'text');
            app.HocLabel = uilabel(app.TchphnTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.TchphnTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.hamTichPhanField = uieditfield(app.TchphnTab, 'text');
            app.tichPhanOptionsButtonGroup = uibuttongroup(app.TchphnTab);
            app.tichPhanOptionsButtonGroup.Title = 'Chon phương pháp tính Tích phân';
            app.HnhthangButton = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.HnhthangButton.Text = 'Hình thang';
            app.HnhthangButton.Value = true;
            app.Simpson13Button = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uiradiobutton(app.tichPhanOptionsButtonGroup);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.nField = uieditfield(app.TchphnTab, 'numeric');
            app.solveTichPhanButton = uibutton(app.TchphnTab, 'push');
            app.solveTichPhanButton.ButtonPushedFcn = createCallbackFcn(app, @solveTichPhanButtonPushed, true);
            app.solveTichPhanButton.Text = 'Tính toán';
            app.NhpcntnhTchphnLabel = uilabel(app.TchphnTab);
            app.NhpcntnhTchphnLabel.Text = 'Nhập cận để tính Tích phân';
            app.aEditFieldLabel = uilabel(app.TchphnTab);
            app.aEditFieldLabel.Text = 'a';
            app.canTichPhanAField = uieditfield(app.TchphnTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.TchphnTab);
            app.bEditFieldLabel.Text = 'b';
            app.canTichPhanBField = uieditfield(app.TchphnTab, 'numeric');
            app.tichphanErrorLabel = uilabel(app.TchphnTab);
            app.tichphanErrorLabel.Text = '';
            app.tichphanResultLabel = uilabel(app.TchphnTab);
            app.tichphanResultLabel.Visible = 'off';
            app.tichphanResultLabel.Text = 'Kết quả:';
            app.resultTichPhan = uilabel(app.TchphnTab);
            app.resultTichPhan.Visible = 'off';
            app.resultTichPhan.Text = '';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.TrngihcKhoahcTnhinHQGTPHChmnhLabel = uilabel(app.GiithiunhmTab);
            app.TrngihcKhoahcTnhinHQGTPHChmnhLabel.Text = 'Trường Đại học Khoa học Tự nhiên, ĐHQG TP Hồ Chí mính';
            app.ncuimnPhngphptnhLabel = uilabel(app.GiithiunhmTab);
            app.ncuimnPhngphptnhLabel.Text = 'Đồ án cuối môn Phương pháp tính';
            app.NhmTHTHLabel = uilabel(app.GiithiunhmTab);
            app.NhmTHTHLabel.Text = 'Nhóm: THTH';
            app.NguynVLcLam21200305Label = uilabel(app.GiithiunhmTab);
            app.NguynVLcLam21200305Label.Text = '1.Nguyễn Vũ Lục Lam  - 21200305';
            app.NguynTunKit21200303Label = uilabel(app.GiithiunhmTab);
            app.NguynTunKit21200303Label.Text = '2. Nguyễn Tuấn Kiệt - 21200303';
            app.DngVnThng21200359Label = uilabel(app.GiithiunhmTab);
            app.DngVnThng21200359Label.Text = '3. Dương Văn Thương - 21200359';
            app.TrnhnhThnh21200354Label = uilabel(app.GiithiunhmTab);
            app.TrnhnhThnh21200354Label.Text = '4. Trịnh Đình Thạnh - 21200354';
            app.NhimvLabel = uilabel(app.GiithiunhmTab);
            app.NhimvLabel.Text = 'Nhiệm vụ:';
            app.CodevthitkgiaodinLabel_3 = uilabel(app.GiithiunhmTab);
            app.CodevthitkgiaodinLabel_3.Text = 'Code và thiết kế giao diện';
            app.CodevthitkgiaodinLabel_2 = uilabel(app.GiithiunhmTab);
            app.CodevthitkgiaodinLabel_2.Text = 'Code và thiết kế giao diện';
            app.VitbocohiuchnhLabel = uilabel(app.GiithiunhmTab);
            app.VitbocohiuchnhLabel.Text = 'Viết báo cáo, hiệu chỉnh.';
            app.CodevthitkgiaodinLabel = uilabel(app.GiithiunhmTab);
            app.CodevthitkgiaodinLabel.Text = 'Code và thiết kế giao diện';
            app.ThnhvinLabel = uilabel(app.GiithiunhmTab);
            app.ThnhvinLabel.Text = 'Thành viên:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end