classdef demo_matlabProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ohmTab                          matlab.ui.container.Tab
        ChnphngphptnhohmListBoxLabel    matlab.ui.control.Label
        methodCalc                      matlab.ui.control.ListBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        HocLabel                        matlab.ui.control.Label
        NhpccgitrxcchnhaubikhongtrngLabel_2  matlab.ui.control.Label
        xArray_2                        matlab.ui.control.EditField
        NhpccgitrycchnhaubikhongtrngLabel_2  matlab.ui.control.Label
        yArray_2                        matlab.ui.control.EditField
        NhphmsEditFieldLabel            matlab.ui.control.Label
        expressionInput                 matlab.ui.control.EditField
        NhpbchEditFieldLabel            matlab.ui.control.Label
        hInput                          matlab.ui.control.NumericEditField
        ChnbcsaisDropDownLabel          matlab.ui.control.Label
        errorOh                         matlab.ui.control.DropDown
        Computexy                       matlab.ui.control.Button
        Computef                        matlab.ui.control.Button
        ErrorsHandle_2                  matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        derivativeValue                 matlab.ui.control.NumericEditField
        KtquEditFieldLabel              matlab.ui.control.Label
        derivativeResult                matlab.ui.control.NumericEditField
        derivativeErrHandle             matlab.ui.control.EditField
        Derivative                      matlab.ui.container.Tab
        NhpccgitrxcchnhaubikhongtrngLabel  matlab.ui.control.Label
        xArray                          matlab.ui.control.EditField
        NhpccgitrycchnhaubikhongtrngLabel  matlab.ui.control.Label
        yArray                          matlab.ui.control.EditField
        ChnphngphpnisuyLabel            matlab.ui.control.Label
        technique                       matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        Compute                         matlab.ui.control.Button
        athcnisuyEditFieldLabel         matlab.ui.control.Label
        interpolationPolynomial         matlab.ui.control.EditField
        KtqunisuyEditFieldLabel         matlab.ui.control.Label
        interpolationResult             matlab.ui.control.EditField
        ErrorsHandle                    matlab.ui.control.EditField
        ErrorsHandleResult              matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel_2  matlab.ui.control.Label
        interpolationNumber             matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: Compute
        function ComputePushed(app, event)
            x = str2num(app.xArray.Value);
            y = str2num(app.yArray.Value);
            app.interpolationResult.Value = " ";
            app.interpolationPolynomial.Value = " ";
            if (length(x) == length(y))
                app.ErrorsHandle.Visible = 'off';
                if (app.technique.Value == "Lagrange")
                    f = Lagrange(x,y);
                else (app.technique.Value == "Newton")
                    f = NewtonInterpolation(x,y);
                end
                if (f == 0)
                    app.interpolationPolynomial.Value = "0";
                else
                app.interpolationPolynomial.Value = string(simplify(f));
                end
                fplot(app.UIAxes,f);
                grid(app.UIAxes, 'on');
                axis(app.UIAxes, [-100 100 -100 100]);
                value = app.interpolationNumber.Value;
                f = app.interpolationPolynomial.Value;
                f = str2func(['@(x)' char(f)]);
                app.interpolationResult.Value = string(f(value));
            else
                app.ErrorsHandle.Visible = 'on';
                app.ErrorsHandle.Value = "Oops, make sure the length of x and y is equal!!!";
            end
        end

        % Value changed function: interpolationNumber
        function interpolationNumberValueChanged(app, event)
            value = app.interpolationNumber.Value;
            f = app.interpolationPolynomial.Value;
            f = str2func(['@(x)' char(f)]);
            app.interpolationResult.Value = string(f(value));
        end

        % Button pushed function: Computexy
        function ComputexyButtonPushed(app, event)
            app.derivativeErrHandle.Visible = 'off';
            app.ErrorsHandle_2.Visible = 'off';
            idx = 0;
            value = app.derivativeValue.Value;
            result = app.derivativeResult.Value;
            x = str2num(app.xArray_2.Value);
            y = str2num(app.yArray_2.Value); 
            for i = 1:length(x)
                if x(i) == value
                    idx = i;
                end
            end
            if length(x) == length(y)
                if idx == 0
                    app.derivativeErrHandle.Visible = 'on';
                    app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!";
                elseif idx ~= 0
                    if (app.methodCalc.Value == "xấp xỉ tiến")
                        if idx == length(x)
                            app.derivativeErrHandle.Visible = 'on';
                            app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx + 1) - y(idx))/(x(idx + 1) - x(idx));
                        end
                    elseif (app.methodCalc.Value == "xấp xỉ lùi")
                        if idx == 1
                            app.derivativeErrHandle.Visible = 'on';
                            app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx) - y(idx - 1))/(x(idx) - x(idx - 1));
                        end
                    elseif (app.methodCalc.Value == "trung tâm")
                        if idx == 1 || idx == length(x)
                            app.derivativeErrHandle.Visible = 'on';
                            app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!"; 
                        else
                        result = (y(idx + 1) - y(idx - 1))/(x(idx + 1) - x(idx - 1));
                        end
                    end
                end
            elseif length(x) ~= length(y)
                app.ErrorsHandle_2.Visible = 'on';
                app.ErrorsHandle_2.Value = "Oops, make sure the length of x and y is equal!!!";
            end
            app.derivativeResult.Value = result;
        end

        % Button pushed function: Computef
        function ComputefButtonPushed(app, event)
            app.derivativeErrHandle.Visible = 'off';
            value = app.derivativeValue.Value;
            result = app.derivativeResult.Value; 
            f = app.expressionInput.Value;
            f = str2func(['@(x)' f]);
            h = app.hInput.Value;
            if h ~= 0
                if (app.methodCalc.Value == "xấp xỉ tiến")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value + h) - f(value))/h;
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (-3*f(value) + 4*f(value + h) - f(value + 2*h))/(2*h);
                    end
                elseif (app.methodCalc.Value == "xấp xỉ lùi")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value) - f(value - h))/h;
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (3*f(value) - 4*f(value - h) + f(value - 2*h))/(2*h);
                    end
                elseif (app.methodCalc.Value == "trung tâm")
                    if (app.errorOh.Value == "O(h)")
                        result = (f(value + h) + f(value) - f(value - h))/(2*h);
                    elseif (app.errorOh.Value == "O(h^2)")
                        result = (f(value + h) + f(value) - f(value - h))/(2*h);
                    end 
                end
            else
                app.derivativeErrHandle.Visible = 'on';
                app.derivativeErrHandle.Value = "Oops, look like something wrong. Please try again!!";
            end       
            app.derivativeResult.Value = result;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.ChnphngphptnhohmListBoxLabel = uilabel(app.ohmTab);
            app.ChnphngphptnhohmListBoxLabel.Text = 'Chọn phương pháp tính đạo hàm';
            app.methodCalc = uilistbox(app.ohmTab);
            app.methodCalc.Items = {'xấp xỉ tiến', 'xấp xỉ lùi', 'trung tâm'};
            app.methodCalc.Value = 'xấp xỉ tiến';
            app.ButtonGroup = uibuttongroup(app.ohmTab);
            app.HocLabel = uilabel(app.ButtonGroup);
            app.HocLabel.Text = 'Hoặc';
            app.NhpccgitrxcchnhaubikhongtrngLabel_2 = uilabel(app.ButtonGroup);
            app.NhpccgitrxcchnhaubikhongtrngLabel_2.Text = 'Nhập các giá trị x (cách nhau bởi khoảng trắng) :';
            app.xArray_2 = uieditfield(app.ButtonGroup, 'text');
            app.NhpccgitrycchnhaubikhongtrngLabel_2 = uilabel(app.ButtonGroup);
            app.NhpccgitrycchnhaubikhongtrngLabel_2.Text = 'Nhập các giá trị y (cách nhau bởi khoảng trắng) :';
            app.yArray_2 = uieditfield(app.ButtonGroup, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.ButtonGroup);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.expressionInput = uieditfield(app.ButtonGroup, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ButtonGroup);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.hInput = uieditfield(app.ButtonGroup, 'numeric');
            app.ChnbcsaisDropDownLabel = uilabel(app.ButtonGroup);
            app.ChnbcsaisDropDownLabel.Text = 'Chọn bậc sai số';
            app.errorOh = uidropdown(app.ButtonGroup);
            app.errorOh.Items = {'O(h)', 'O(h^2)'};
            app.errorOh.Value = 'O(h)';
            app.Computexy = uibutton(app.ButtonGroup, 'push');
            app.Computexy.ButtonPushedFcn = createCallbackFcn(app, @ComputexyButtonPushed, true);
            app.Computexy.Text = 'Tính toán';
            app.Computef = uibutton(app.ButtonGroup, 'push');
            app.Computef.ButtonPushedFcn = createCallbackFcn(app, @ComputefButtonPushed, true);
            app.Computef.Text = 'Tính toán';
            app.ErrorsHandle_2 = uieditfield(app.ButtonGroup, 'text');
            app.ErrorsHandle_2.Editable = 'off';
            app.ErrorsHandle_2.Visible = 'off';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.derivativeValue = uieditfield(app.ohmTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.derivativeResult = uieditfield(app.ohmTab, 'numeric');
            app.derivativeErrHandle = uieditfield(app.ohmTab, 'text');
            app.derivativeErrHandle.Visible = 'off';
            app.Derivative = uitab(app.TabGroup);
            app.Derivative.Title = 'Nội suy';
            app.NhpccgitrxcchnhaubikhongtrngLabel = uilabel(app.Derivative);
            app.NhpccgitrxcchnhaubikhongtrngLabel.Text = 'Nhập các giá trị x (cách nhau bởi khoảng trắng) :';
            app.xArray = uieditfield(app.Derivative, 'text');
            app.NhpccgitrycchnhaubikhongtrngLabel = uilabel(app.Derivative);
            app.NhpccgitrycchnhaubikhongtrngLabel.Text = 'Nhập các giá trị y (cách nhau bởi khoảng trắng) :';
            app.yArray = uieditfield(app.Derivative, 'text');
            app.ChnphngphpnisuyLabel = uilabel(app.Derivative);
            app.ChnphngphpnisuyLabel.Text = 'Chọn phương pháp nội suy :';
            app.technique = uidropdown(app.Derivative);
            app.technique.Items = {'Lagrange', 'Newton    ', ''};
            app.technique.Value = 'Newton    ';
            app.UIAxes = uiaxes(app.Derivative);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Compute = uibutton(app.Derivative, 'push');
            app.Compute.ButtonPushedFcn = createCallbackFcn(app, @ComputePushed, true);
            app.Compute.Text = 'Tính toán';
            app.athcnisuyEditFieldLabel = uilabel(app.Derivative);
            app.athcnisuyEditFieldLabel.Text = 'Đa thức nội suy';
            app.interpolationPolynomial = uieditfield(app.Derivative, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.Derivative);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.interpolationResult = uieditfield(app.Derivative, 'text');
            app.ErrorsHandle = uieditfield(app.Derivative, 'text');
            app.ErrorsHandle.Editable = 'off';
            app.ErrorsHandle.Visible = 'off';
            app.ErrorsHandleResult = uieditfield(app.Derivative, 'text');
            app.ErrorsHandleResult.Visible = 'off';
            app.NhpgitrcnnisuyEditFieldLabel_2 = uilabel(app.Derivative);
            app.NhpgitrcnnisuyEditFieldLabel_2.Text = 'Nhập giá trị cần nội suy';
            app.interpolationNumber = uieditfield(app.Derivative, 'numeric');
            app.interpolationNumber.ValueChangedFcn = createCallbackFcn(app, @interpolationNumberValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = demo_matlabProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end